classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetsimulationPanel            matlab.ui.container.Panel
        AgentsEditFieldLabel          matlab.ui.control.Label
        AgentsEditField               matlab.ui.control.NumericEditField
        AttackersEditFieldLabel       matlab.ui.control.Label
        AttackersEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        ReplaysimulationButton        matlab.ui.control.Button
        SetnumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SetnumberofiterationsSpinner  matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        Env % multi-robot environment
        NumOfRob; % total # of robots
        NumOfAgents; % number of agents
        OcMap; % Occupancy map
        FieldLen = 100;% length of field side
        PZoneLen = 20;% Protected Zone side length
        pZoneMaxX; % Protected Zone max x position
        pZoneMinX; % Protected Zone min x position
        pZoneMaxY; % Protected Zone max y position
        pZoneMinY; % Protected Zone min y position
        Pzone_poly; % pzone polyshape
        Poses; % Position matrix for the robots
        FrameSize = 10;
        FrameInitAg = 0.2; % Frame ratio for initial agent positions
        LifeFlags; % Life markers for the robots
        SimTime = 60; % total simulation time (sec)
        SampleTime = 0.1; % time stemp (sec)
        TimeVec; % time vector
        ATC_EVADE_RAD = 3; % attackers evade radius
        ATC_TER_RAD = 1; % attackers terminated radius
        roles; % roles vector
    end
    
    methods (Access = private)
        
         % check if the protected zone is covered
        function covered=is_pz_covered(app,poly_vec)
            % create one polyshape
            u_cell = union(poly_vec);
            % intersect the pzone and the cells
            inter = intersect(u_cell,app.Pzone_poly);
            covered = area(inter) == area(app.Pzone_poly);
        end
        
%         % check if the protected zone is covered
%         function covered=is_pz_covered(app,vertices,cells)
%             covered = false;
%             % set pzone coordinates
%             pzone_x = linespace(app.pZoneMinX,app.pZoneMaxX,app.PZoneLen)'; 
%             pzone_x = [pzone_x;pzone_x];
%             pzone_y = linespace(app.pZoneMinY,app.pZoneMaxY,app.PZoneLen)';
%             pzone_y = [pzone_y;pzone_y];
%             
%             % set fist cell coordinates
%             curr_cell = vertices(cells(1));
%             % check if pzone is covered by the first cell
%             [in,on] = inpolygon(pzone_x,pzone_y,curr_cell(:,1),curr_cell(:,2));
%             % set current cover status
%             curr_cover = in|on;
%             
%             % run on all the others cells
%             for i=2:length(cells)
%                 % set current cell coordinates
%                 curr_cell = vertices(cells(i));
%                 cx = curr_cell(:,1);
%                 cy = curr_cell(:,2);        
%                 % check if pzone is covered by current cell
%                 [in,on] = inpolygon(pzone_x,pzone_y,cx,cy);
%                 % set current cover status
%                 curr_cover = curr_cover|(in|on);
%                 % check if all pzone is fully covered
%                 if ~any(curr_cover(:) == 0)
%                     covered = true;
%                     break;
%                 end
%             end
%         end


        % return position for attacker
        function res = get_atacker_init_pos(app)
            pos1 = (rand(1,1)-0.5)*2; % get rand in [-1,1]
            negIdx = (pos1<0);
            posIdx = (pos1>=0);
            % map to [-10,0] or [100,110]
            pos1 = app.FrameSize .* pos1;
            pos1 = ((app.FieldLen/2)+ pos1).*posIdx ...
                + (-(app.FieldLen/2)+ pos1).*negIdx  ...
                + app.FieldLen/2;
            % map to [-10,110]
            pos2 = (-app.FrameSize+(app.FieldLen+(2*app.FrameSize)) .* rand(1,1));
            
            coinFlip = (rand(1,1)-0.5)*2;
            if coinFlip >= 0
                res = [pos1; pos2;pi*rand(1,1)];
            else
                res = [pos2; pos1;pi*rand(1,1)];
            end
        end


        % return position for defender
        function pos = get_agent_init_pos(app)
            offset = app.FrameInitAg * app.FieldLen;
            frameSize = offset*2+app.PZoneLen;
            pos = [(rand(2, 1))*frameSize+offset;pi*rand(1,1)];
        end
        
        % get relevent point of protected zone
        function target = get_point_of_pzone(app,point)
            curr_x = point(1);
            curr_y = point(2);
            if app.pZoneMinX <= curr_x && curr_x <= app.pZoneMaxX
                x = curr_x;
            elseif curr_x > app.pZoneMaxX
                x = app.pZoneMaxX;
            else
                x = app.pZoneMinX;
            end 
            
            if app.pZoneMinY <= curr_y && curr_y <= app.pZoneMaxY
                y = curr_y;
            elseif curr_x > app.pZoneMaxY
                y = app.pZoneMaxY;
            else
                y = app.pZoneMinY;
            end
            target = [x y];
        end
        
%         function idx = get_closest_point(app,points)
%             min_dis = Inf;
%             idx = 0;
%             for i=1:length(points)
%                 % get relevent point
%                 pZone = app.get_point_of_pzone(points(i,:));
%                 % calc distance
%                 curr_dis = norm(pZone - points(i));
%                 % update current
%                 if curr_dis < min_dis
%                     idx = i;
%                     min_dis = curr_dis;
%                 end
%             end
%         end
        
function [w,evade] = calc_atk_angular_speed(app,evade_radius,terminate_radius,pos,robots,idx)
            w = 0;
            evade = false;
            temp_w = 0;
            heading = mod(pos(3),2*pi);
            % run on all robots
            for i=1:length(robots)
                u = robots(:,i) - pos(1:2);
                v = [0;1];
                % calc distance
                distance = norm(u);
                if terminate_radius < distance && distance <= evade_radius
                    % calc angle between the vectors
                    cos_theta = max(min(dot(u,v)/distance,1),-1);
                    theta = real(acosd(cos_theta));
                    r_pos = robots(:,i);
                    if r_pos(2) < pos(2)
                        theta = theta + pi;   
                    elseif r_pos(2) == pos(2)
                        if r_pos(1) < pos(1)
                            theta = theta + pi;
                        end
                    end
                    theta = mod(theta,2*pi);
                    diff = heading - theta;
                    if diff >= -pi/3 && diff <=  0
                       temp_w = interp1([0,pi/3],[0.5,2],pi/3+diff);
                       if  abs(temp_w) > abs(w)
                           w = temp_w;
                           evade = true;
                       end
                    elseif diff <= pi/3 && diff > 0
                       w = interp1([0,pi/3],[-2,-0.5],pi/3-diff);
                       evade = true;
                       break;
                    end
                elseif distance <= terminate_radius % TODO: maybe need to place somewhere else...
                    % set the attacker as terminated
                    app.LifeFlags(idx) = 0;
                end
            end
        end
        
        % attackers controler
        function vel = AtCtrl(app, rIdx)
            pos = app.Poses(:,rIdx);
            
            % check if there is a defender in radius of 3 meters, if there
            % is evade
            defenders_pos = app.Poses(1:2,1:app.NumOfAgents);
            [w,evade] = app.calc_atk_angular_speed(app.ATC_EVADE_RAD,app.ATC_TER_RAD,pos,defenders_pos,rIdx);
            if ~evade
                % move toward protected zone
                % get point to move to
                target = app.get_point_of_pzone(pos);
                w = utils.calc_angular_speed_by_point(pos,target); 
            end
            vel = bodyToWorld([1;0;w],pos);
        end
        
        % Determine role
        function role=determine_role(app,cell)
            inter = intersect(cell,app.Pzone_poly);
            role = int8(area(inter) ~= 0);%TODO: add error tolerance +-5%
        end
        
        % Agents keepers controler
        
        function vel = AgKeepCtrl(app, rIdx,is_cover,neighbors,cells)
            pose = app.Poses(:,rIdx);
            vel = bodyToWorld([0;0;0],pose);
            w = 0;
            v=0;
            % Find closest attacker, which the agent is closest to.
            cl_to_neigh = find(neighbors(rIdx,:));
            ag_pos = pose(1:2);
            min_dist = Inf;
            closest_att=-1;


            for i=1:numel(cl_to_neigh)
                cl_to_att = find(neighbors(cl_to_neigh(i),:));
                if cl_to_att(1) == rIdx
                    att_pos = app.Poses(1:2, cl_to_neigh(i));
                    dist = norm(att_pos-ag_pos);
                    if dist<min_dist
                        min_dist =dist;
                        closest_att = cl_to_neigh(i);
                    end
                end
            end
            
            
            if closest_att==-1
                % ******* Passive defence policy *********
                hold on
                scatter(ag_pos(1),ag_pos(2),'c',"filled");
            else
                % ******* Active defence policy ********
                
                % calc angular speed
                % findedge middle
                inter = intersect(cells{rIdx}',cells{closest_att}','rows');
                ver1 = inter(1,:)';
                ver2 = inter(2,:)';
                target = (ver1 + ver2)./2;
                w = utils.calc_angular_speed_by_point(pose,target);
                v=1;
                
                
                hold on
                scatter(ag_pos(1),ag_pos(2),'m',"filled");
            end
            vel = bodyToWorld([v;0;w],pose);
        end
        
        % Agents chasers controler
        function vel = AgChaseCtrl(app, rIdx)
            pose = app.Poses(:,rIdx);
            vel = bodyToWorld([0;0;0],pose);
        end
  
        function poly_vec=create_polyVec(app,cells,numAgs)
            poly_vec = polyshape.empty(numAgs,0);
            % create polyshape
            for i=1:numAgs
                 % get current cell coordinates
                curr_cell = cells{i};
                cx = curr_cell(1,:);
                cy = curr_cell(2,:);   
                % add cell to polyshape vector
                curr_poly = polyshape(cx,cy);
                poly_vec(i) = curr_poly;
                app.roles(i) = app.determine_role(curr_poly);
            end
        end
        
        % robots controler
        function vel = robCtrl(app,vel)
            % get voronoi cells
            lBound = [-app.FrameSize -app.FrameSize];
            hBound = [app.FieldLen+app.FrameSize app.FieldLen+app.FrameSize];
            cells = utils.get_voronoi(app.Poses(1:2,:),lBound,hBound);
            
            % create polyshapes for each voronoi cell and assign role for each agent
            poly_vec = app.create_polyVec(cells,app.NumOfAgents);
            
            % get neighbors of each robot
            neighbors = utils.get_neighbors(app.NumOfRob,app.NumOfAgents,app.Poses(1:2,:),cells);  
            
            % check if pzone is coverd by friendly voronoi cells
            is_cover = app.is_pz_covered(poly_vec);
            
            % run controllers
            for i=1:app.NumOfRob
                if i <= app.NumOfAgents
                    if app.roles(i)
                       % Keeper controller
                       vel(:,i) = app.AgKeepCtrl(i,is_cover,neighbors,cells);
                    else
                       % Chaser controller
                       vel(:,i) = app.AgChaseCtrl(i);
                    end
                elseif app.LifeFlags(i) == 1
                    vel(:,i) = app.AtCtrl(i);
                else 
                    % remove attacker from field
                    scatter(app.Poses(1,i),app.Poses(2,i),100,'w','filled');
                end
            end
        end
        
        % run the simulation loop
        function simulation_loop(app)
            vel=zeros(3, app.NumOfRob);
            % run on each timestamp
           % vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
            % set empty roles
            app.roles = zeros(1,app.NumOfAgents);
            for currStemp =2:numel(app.TimeVec)
                % update env
                app.Env(1:app.NumOfRob, app.Poses);
                xlim([0 100]);   % Without this, axis resizing can slow things down
                ylim([0 100]); 
                
                % display voronoi
                % set display off only if vor exist
                if(exist('vor',"var"))
                    set(vor,'Visible','off')
                end
                hold on
                vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
                set(vor,'Visible','on')
                % run robot controller
                vel = app.robCtrl(vel);

                % Calculate next timestamp poses
                app.Poses = app.Poses + vel*app.SampleTime;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create occupancy map
            app.OcMap = binaryOccupancyMap(app.FieldLen, app.FieldLen);
            assignin("base","map", app.OcMap)
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            x = fieldMid-pzMid+1:fieldMid+pzMid;
            y = fieldMid-pzMid+1:fieldMid+pzMid;
            app.pZoneMaxX = x(end);
            app.pZoneMinX = x(1);
            app.pZoneMaxY = y(end);
            app.pZoneMinY = y(1);
            pzone_x = [app.pZoneMinX;app.pZoneMinX;app.pZoneMaxX;app.pZoneMaxX];
            pzone_y = [app.pZoneMinY;app.pZoneMaxY;app.pZoneMaxY;app.pZoneMinY];
            app.Pzone_poly = polyshape(pzone_x,pzone_y); 
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(app.OcMap, xy, ones(app.PZoneLen^2,1))
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            % Create a multi-robot environment
            
            % get User values
            numOfAgents = app.AgentsEditField.Value;
            numOfAttackers = app.AttackersEditField.Value;
            mumIter = app.SetnumberofiterationsSpinner.Value;
            
            app.NumOfRob = numOfAttackers+numOfAgents;
            app.NumOfAgents = numOfAgents;
            
            % init multi-robot environment
            app.Env = MultiRobotEnv(app.NumOfRob);
            app.Env.robotRadius = 1 ;
            app.Env.showTrajectory = false;
            app.Env.mapName = "map";
            
            % divide robots to groups
            a = repmat([0 0 1],[numOfAgents 1]);
            b = repmat([1 0 0],[numOfAttackers 1]);
            app.Env.robotColors = [a;b];
            % Initialize poses
            app.Poses= [];
            for i=1:app.NumOfRob
                if i<= numOfAgents
                    pos = app.get_agent_init_pos();
                    app.Poses=[app.Poses pos];
                else
                    pos = app.get_atacker_init_pos();
                    app.Poses=[app.Poses pos];
                end
            end
            
            % set time vector
            app.TimeVec = 0:app.SampleTime:app.SimTime;
            % initialize life markers
            app.LifeFlags = ones(app.NumOfRob,1);    
            %try
                app.simulation_loop()
            %catch
                % delete multi-robot environment
                %delete(app.Env);
            %end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close all figures
            close all
            % clear all variables
            evalin('base','clear all')
            % delete app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetsimulationPanel = uipanel(app.UIFigure);
            app.SetsimulationPanel.Title = 'Set simulation';
            app.AgentsEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AgentsEditField.Value = 2;
            app.AttackersEditFieldLabel = uilabel(app.SetsimulationPanel);
            app.AttackersEditFieldLabel.Text = 'Attackers';
            app.AttackersEditField = uieditfield(app.SetsimulationPanel, 'numeric');
            app.AttackersEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.SetsimulationPanel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.SetnumberofiterationsSpinnerLabel = uilabel(app.SetsimulationPanel);
            app.SetnumberofiterationsSpinnerLabel.Text = 'Set number of iterations';
            app.SetnumberofiterationsSpinner = uispinner(app.SetsimulationPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end